classdef Calculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Ingresanumero1EditFieldLabel  matlab.ui.control.Label
        Ingresanumero1EditField       matlab.ui.control.NumericEditField
        Ingresanumero2EditFieldLabel  matlab.ui.control.Label
        Ingresanumero2EditField       matlab.ui.control.NumericEditField
        Numero1EditFieldLabel         matlab.ui.control.Label
        Numero1EditField              matlab.ui.control.EditField
        Numero2EditFieldLabel         matlab.ui.control.Label
        Numero2EditField              matlab.ui.control.EditField
        CaluladoraAppDesignerLabel    matlab.ui.control.Label
        ResultadosLabel               matlab.ui.control.Label
        ResultadoEditFieldLabel       matlab.ui.control.Label
        ResultadoEditField            matlab.ui.control.NumericEditField
        ResultadoEditField_2Label     matlab.ui.control.Label
        ResultadoEditField_2          matlab.ui.control.EditField
        Button                        matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Button Add
            DatoInt1=app.Ingresanumero1EditField.Value;
            DatoInt2=app.Ingresanumero2EditField.Value;
            ResultadoInt3=DatoInt2 + DatoInt1
            app.ResultadoEditField.Value=ResultadoInt3;
             %app.Numero1EditField
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % Button Rest
            DatoInt1=app.Ingresanumero1EditField.Value;
            DatoInt2=app.Ingresanumero2EditField.Value;
            ResultadoInt3=DatoInt2 - DatoInt1
            app.ResultadoEditField.Value=ResultadoInt3;            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % Button Multiply
            DatoInt1=app.Ingresanumero1EditField.Value;
            DatoInt2=app.Ingresanumero2EditField.Value;
            ResultadoInt3=DatoInt2 * DatoInt1
            app.ResultadoEditField.Value=ResultadoInt3;                
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % Button Divide
            DatoInt1=app.Ingresanumero1EditField.Value;
            DatoInt2=app.Ingresanumero2EditField.Value;
            ResultadoInt3=DatoInt2 / DatoInt1
            app.ResultadoEditField.Value=ResultadoInt3;             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Ingresanumero1EditFieldLabel = uilabel(app.UIFigure);
            app.Ingresanumero1EditFieldLabel.Text = 'Ingresa numero 1';
            app.Ingresanumero1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Ingresanumero2EditFieldLabel = uilabel(app.UIFigure);
            app.Ingresanumero2EditFieldLabel.Text = 'Ingresa numero 2';
            app.Ingresanumero2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Numero1EditFieldLabel = uilabel(app.UIFigure);
            app.Numero1EditFieldLabel.Text = 'Numero 1';
            app.Numero1EditField = uieditfield(app.UIFigure, 'text');
            app.Numero2EditFieldLabel = uilabel(app.UIFigure);
            app.Numero2EditFieldLabel.Text = 'Numero 2';
            app.Numero2EditField = uieditfield(app.UIFigure, 'text');
            app.CaluladoraAppDesignerLabel = uilabel(app.UIFigure);
            app.CaluladoraAppDesignerLabel.Text = 'Caluladora AppDesigner';
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados';
            app.ResultadoEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadoEditFieldLabel.Text = 'Resultado';
            app.ResultadoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultadoEditField.Editable = 'off';
            app.ResultadoEditField_2Label = uilabel(app.UIFigure);
            app.ResultadoEditField_2Label.Text = 'Resultado';
            app.ResultadoEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '*';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '/';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end